classdef RadarDisplayApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        NextButton         matlab.ui.control.Button
        PrevButton         matlab.ui.control.Button
        PauseButton        matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        FrameSlider        matlab.ui.control.Slider
        FrameSliderLabel   matlab.ui.control.Label
        UIAxes_Cluster     matlab.ui.control.UIAxes
        UIAxes_PC3D        matlab.ui.control.UIAxes
        UIAxes_RangeAngle  matlab.ui.control.UIAxes
        UIAxes_RangeFFT    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        TotalFrames     = 50;  % --- 重要: 在这里修改你的数据总帧数 ---
        CurrentFrame    = 1;    % 当前帧号
        PlayerTimer             % 用于自动播放的计时器
        IsPlaying       = false % 播放状态标志
    end

    methods (Access = private)

        function startupFcn(app)
            % --- 【【【 新增的修复代码：手动连接UI与回调函数 】】】 ---
            % 这是一个变通方法，将本应在 createComponents 中的连接代码移到这里
            try
                app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
                app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
                app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
                app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
                app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
                disp('UI组件与回调函数的连接已尝试手动建立。');
            catch ME
                uialert(app.UIFigure, ['手动连接UI失败: ' ME.message], 'Error');
                return;
            end
            % --- 【【【 新增代码结束 】】】 ---

            % --- 1. 自动将整个项目文件夹添加到 MATLAB 路径 ---
            try
                project_root = fileparts(mfilename('fullpath'));
                addpath(genpath(project_root));
                disp(['成功将整个项目文件夹添加到路径: ', project_root]);
            catch ME
                uialert(app.UIFigure, ['初始化项目路径失败: ' ME.message], 'Error');
                return;
            end
            % --- 2. 载入雷达配置 (现在可以直接调用了) ---
            try
                config2243;
                workspace_vars = evalin('base', 'who');
                if ismember('nFrm', workspace_vars)
                    app.TotalFrames = evalin('base', 'nFrm');
                end
            catch ME
                uialert(app.UIFigure, ['载入 config2243.m 失败: ' ME.message], 'Error');
                return;
            end
            % --- 3. 初始化UI界面 (保持不变) ---
            app.FrameSlider.Limits = [1, app.TotalFrames];
            app.FrameSlider.MajorTicks = unique([1, round(linspace(1, app.TotalFrames, 5)), app.TotalFrames]);
            app.FrameSliderLabel.Text = 'Frame';
            % --- 4. 初始化定时器 (保持不变) ---
            app.PlayerTimer = timer('ExecutionMode', 'fixedRate','Period', 0.2,'TimerFcn', @(~,~) app.timerCallback());
            % --- 5. 显示第一帧 (保持不变) ---
            app.updateAllPlots(app.CurrentFrame);
        end

        % (updateAllPlots 和其他所有回调函数的代码保持和你原文中一样，无需改动)
        function updateAllPlots(app, iFrm)
            % ... (代码不变)
            if iFrm < 1 || iFrm > app.TotalFrames, return; end
            app.CurrentFrame = iFrm;
            app.FrameSlider.Value = iFrm;
            app.FrameSliderLabel.Text = sprintf('Frame: %d / %d', iFrm, app.TotalFrames);
            drawnow;
            try
                radarData = readBin(iFrm, 0);
                [fftRsltRg, pcRg] = fftRange(radarData, 'pcEn', 0, 'drawEn', 0);
                plot(app.UIAxes_RangeFFT, pcRg.range, pcRg.val, 'LineWidth', 1.5);
                title(app.UIAxes_RangeFFT, 'Range FFT Profile');
                xlabel(app.UIAxes_RangeFFT, 'Range (m)'); ylabel(app.UIAxes_RangeFFT, 'Magnitude'); grid(app.UIAxes_RangeFFT, 'on');
                [pwRA, ~] = dbfProc1D(fftRsltRg, 'pcEn', 0, 'limitR', [0, 8], 'resAng', 1, 'drawEn', 0);
                imagesc(app.UIAxes_RangeAngle, pwRA.ang, pwRA.rg, pwRA.pw_lin);
                colormap(app.UIAxes_RangeAngle, 'jet');
                title(app.UIAxes_RangeAngle, 'Range-Angle Heatmap');
                xlabel(app.UIAxes_RangeAngle, 'Angle (degrees)'); ylabel(app.UIAxes_RangeAngle, 'Range (m)');
                pc3D = pcFrom4DFFT(radarData, 'limitR', [0.8, 7.2], 'limitX', [-3, 3], 'limitY', [1.2, 6.8], 'limitZ', [0, 2], 'nPeakAz', 1, 'nPeakEl', 3, 'drawEn', 0);
                scatter3(app.UIAxes_PC3D, pc3D.x, pc3D.y, pc3D.z, 36, pc3D.vel, 'filled');
                view(app.UIAxes_PC3D, 2); axis(app.UIAxes_PC3D, [-4 4 0 8 -2 2]);
                title(app.UIAxes_PC3D, '3D Point Cloud (BEV)');
                xlabel(app.UIAxes_PC3D, 'X (m)'); ylabel(app.UIAxes_PC3D, 'Y (m)'); grid(app.UIAxes_PC3D, 'on');
                clusterXYZ = pcCluster3D([pc3D.x, pc3D.y, pc3D.z], 'pw', [], 'vel', pc3D.vel, 'limitX', [-3, 3], 'limitY', [1.2, 6.8], 'limitZV', [0, 2], 'drawEn', 0);
                cla(app.UIAxes_Cluster); hold(app.UIAxes_Cluster, 'on');
                if ~isempty(clusterXYZ)
                    for iCluster = 1:length(clusterXYZ)
                        points = clusterXYZ(iCluster).points;
                        scatter3(app.UIAxes_Cluster, points(:,1), points(:,2), points(:,3), 50, 'filled');
                    end
                end
                hold(app.UIAxes_Cluster, 'off'); view(app.UIAxes_Cluster, 2); axis(app.UIAxes_Cluster, [-4 4 0 8 -2 2]);
                title(app.UIAxes_Cluster, '3D Clustering Result (BEV)');
                xlabel(app.UIAxes_Cluster, 'X (m)'); ylabel(app.UIAxes_Cluster, 'Y (m)'); grid(app.UIAxes_Cluster, 'on');
            catch ME
                cla(app.UIAxes_RangeFFT); text(app.UIAxes_RangeFFT, 0.1, 0.5, ['Error: ' ME.message], 'Color', 'red');
                cla(app.UIAxes_RangeAngle); cla(app.UIAxes_PC3D); cla(app.UIAxes_Cluster);
                uialert(app.UIFigure, ['处理第 ' numstr(iFrm) ' 帧时出错: ' ME.message], 'Processing Error');
                if app.IsPlaying, PauseButtonPushed(app, []); end
            end
        end
        function timerCallback(app)
            % ... (代码不变)
            if app.CurrentFrame < app.TotalFrames, app.updateAllPlots(app.CurrentFrame + 1);
            else, stop(app.PlayerTimer); app.IsPlaying = false; end
        end
        function PlayButtonPushed(app, event)
            % ... (代码不变)
            if ~app.IsPlaying && app.CurrentFrame < app.TotalFrames, start(app.PlayerTimer); app.IsPlaying = true; end
        end
        function PauseButtonPushed(app, event)
            % ... (代码不变)
            if app.IsPlaying, stop(app.PlayerTimer); app.IsPlaying = false; end
        end
        function PrevButtonPushed(app, event)
            % ... (代码不变)
            if app.IsPlaying, PauseButtonPushed(app, []); end, app.updateAllPlots(app.CurrentFrame - 1);
        end
        function NextButtonPushed(app, event)
            % ... (代码不变)
            if app.IsPlaying, PauseButtonPushed(app, []); end, app.updateAllPlots(app.CurrentFrame + 1);
        end
        function FrameSliderValueChanged(app, event)
            % ... (代码不变)
            if app.IsPlaying, PauseButtonPushed(app, []); end
            newFrame = round(app.FrameSlider.Value);
            if newFrame ~= app.CurrentFrame, app.updateAllPlots(newFrame); end
        end
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_RangeFFT = uiaxes(app.UIFigure);
            title(app.UIAxes_RangeFFT, 'UIAxesRangeFFT')
            xlabel(app.UIAxes_RangeFFT, 'X')
            ylabel(app.UIAxes_RangeFFT, 'Y')
            zlabel(app.UIAxes_RangeFFT, 'Z')
            app.UIAxes_RangeAngle = uiaxes(app.UIFigure);
            title(app.UIAxes_RangeAngle, 'UIAxesRangeAngle')
            xlabel(app.UIAxes_RangeAngle, 'X')
            ylabel(app.UIAxes_RangeAngle, 'Y')
            zlabel(app.UIAxes_RangeAngle, 'Z')
            app.UIAxes_PC3D = uiaxes(app.UIFigure);
            title(app.UIAxes_PC3D, 'UIAxesPC3D')
            xlabel(app.UIAxes_PC3D, 'X')
            ylabel(app.UIAxes_PC3D, 'Y')
            zlabel(app.UIAxes_PC3D, 'Z')
            app.UIAxes_Cluster = uiaxes(app.UIFigure);
            title(app.UIAxes_Cluster, 'UIAxesCluster')
            xlabel(app.UIAxes_Cluster, 'X')
            ylabel(app.UIAxes_Cluster, 'Y')
            zlabel(app.UIAxes_Cluster, 'Z')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'FrameSlider';
            app.FrameSlider = uislider(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadarDisplayApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end